classdef diferenciasView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        calculate                      matlab.ui.control.Button
        TipodiferenciaPanel            matlab.ui.container.Panel
        flagPrecisa                    matlab.ui.control.DropDown
        PrecisinLabel                  matlab.ui.control.Label
        tipoDiferencia                 matlab.ui.control.DropDown
        TipoDropDownLabel              matlab.ui.control.Label
        functionBi                     matlab.ui.control.EditField
        FuncinLabel                    matlab.ui.control.Label
        DiferenciasNumricasLabel       matlab.ui.control.Label
        LlenelossiguientescamposPanel  matlab.ui.container.Panel
        hNumeric                       matlab.ui.control.NumericEditField
        hLabel                         matlab.ui.control.Label
        Xi                             matlab.ui.control.NumericEditField
        XiEditFieldLabel               matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        ResultadoLabel                 matlab.ui.control.Label
        ResultadoTabla                 matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calculate
        function calculateButtonPushed(app, event)

            try
                f = entradaFuncion(app.functionBi.Value);
                xi = app.Xi.Value;
                h = app.hNumeric.Value;
            catch ex
                errordlg(ex.message, 'Error de Datos Ingresados');
                return
            end

            type = app.tipoDiferencia.Value;
            flag = app.flagPrecisa.Value;

            if flag == "Alta"
                precisa = true;
            else
                precisa = false;
            end

            try
                if type == "Adelante"
                    resultado = diferenciaNumericaAdelante(f, xi, h, precisa);
                end

                if type == "Centrada"
                    resultado = diferenciaNumericaCentro(f, xi, h, precisa);
                end
                        
                if type == "Atras"
                    resultado = diferenciaNumericaAtras(f, xi, h, precisa);
                end
            catch ex
                errordlg(ex.message, 'Error de Ejecución');
                return
            end

            app.ResultadoTabla.Data = resultado;

        end

        % Value changed function: functionBi
        function functionBiValueChanged(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {392, 392};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LlenelossiguientescamposPanel = uipanel(app.LeftPanel);
            app.LlenelossiguientescamposPanel.Title = 'Llene los siguientes campos';
            app.XiEditFieldLabel = uilabel(app.LlenelossiguientescamposPanel);
            app.XiEditFieldLabel.Text = 'Xi';
            app.Xi = uieditfield(app.LlenelossiguientescamposPanel, 'numeric');
            app.hLabel = uilabel(app.LlenelossiguientescamposPanel);
            app.hLabel.Text = 'h';
            app.hNumeric = uieditfield(app.LlenelossiguientescamposPanel, 'numeric');
            app.DiferenciasNumricasLabel = uilabel(app.LeftPanel);
            app.DiferenciasNumricasLabel.Text = 'Diferencias Numéricas';
            app.FuncinLabel = uilabel(app.LeftPanel);
            app.FuncinLabel.Text = 'Función';
            app.functionBi = uieditfield(app.LeftPanel, 'text');
            app.functionBi.ValueChangedFcn = createCallbackFcn(app, @functionBiValueChanged, true);
            app.TipodiferenciaPanel = uipanel(app.LeftPanel);
            app.TipodiferenciaPanel.Title = 'Tipo diferencia';
            app.TipoDropDownLabel = uilabel(app.TipodiferenciaPanel);
            app.TipoDropDownLabel.Text = 'Tipo';
            app.tipoDiferencia = uidropdown(app.TipodiferenciaPanel);
            app.tipoDiferencia.Items = {'Adelante', 'Centrada', 'Atras'};
            app.tipoDiferencia.Placeholder = 'Seleccione...';
            app.tipoDiferencia.Value = 'Adelante';
            app.PrecisinLabel = uilabel(app.TipodiferenciaPanel);
            app.PrecisinLabel.Text = 'Precisión';
            app.flagPrecisa = uidropdown(app.TipodiferenciaPanel);
            app.flagPrecisa.Items = {'Alta', 'Baja'};
            app.flagPrecisa.Placeholder = 'Seleccione...';
            app.flagPrecisa.Value = 'Baja';
            app.calculate = uibutton(app.LeftPanel, 'push');
            app.calculate.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculate.Text = 'Calcular';
            app.RightPanel = uipanel(app.GridLayout);
            app.ResultadoTabla = uitable(app.RightPanel);
            app.ResultadoTabla.ColumnName = '';
            app.ResultadoTabla.RowName = {};
            app.ResultadoLabel = uilabel(app.RightPanel);
            app.ResultadoLabel.Text = 'Resultado';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = diferenciasView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end